classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        ChooseImageButton     matlab.ui.control.Button
        Image_2               matlab.ui.control.Image
        OriginalImageLabel    matlab.ui.control.Label
        IdentifiedCoinsLabel  matlab.ui.control.Label
        PenniesLabel          matlab.ui.control.Label
        NicklesLabel          matlab.ui.control.Label
        DimesLabel            matlab.ui.control.Label
        QuartersLabel         matlab.ui.control.Label
        TotalLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg'; '*.jpeg'; '*.png'; '*.bmp'}, "Choose Image");
            imageLocation = strcat(pathname, filename);
            myImage = imread(imageLocation);
            
            imshow(myImage, 'parent', uiaxes(app.UIFigure, 'position', [19 70 610 430]));
            
            [counts, t, c, r, myImage] = idcoins(myImage);
            
            imshow('test.png', 'parent', uiaxes(app.UIFigure, 'position', [639 70 610 430]));
            
            app.PenniesLabel.Text = sprintf("Pennies: %d", counts(1));
            app.NicklesLabel.Text = sprintf("Nickles: %d", counts(2));
            app.DimesLabel.Text = sprintf("Dimes: %d", counts(3));
            app.QuartersLabel.Text = sprintf("Quarters: %d", counts(4));
            app.TotalLabel.Text = sprintf("Total: $%.2f", t);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.Image_2 = uiimage(app.UIFigure);
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.IdentifiedCoinsLabel = uilabel(app.UIFigure);
            app.IdentifiedCoinsLabel.Text = 'Identified Coins';
            app.PenniesLabel = uilabel(app.UIFigure);
            app.PenniesLabel.Text = 'Pennies:';
            app.NicklesLabel = uilabel(app.UIFigure);
            app.NicklesLabel.Text = 'Nickles:';
            app.DimesLabel = uilabel(app.UIFigure);
            app.DimesLabel.Text = 'Dimes:';
            app.QuartersLabel = uilabel(app.UIFigure);
            app.QuartersLabel.Text = 'Quarters:';
            app.TotalLabel = uilabel(app.UIFigure);
            app.TotalLabel.Text = 'Total: $';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end